classdef appfunc_solv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LossTextArea                   matlab.ui.control.TextArea
        LossTextAreaLabel              matlab.ui.control.Label
        visualresultButton             matlab.ui.control.Button
        SolutionTextArea               matlab.ui.control.TextArea
        SolutionTextAreaLabel          matlab.ui.control.Label
        AccuratesolutionTextArea       matlab.ui.control.TextArea
        AccuratesolutionTextAreaLabel  matlab.ui.control.Label
        ThedimensionofthematrixEditField  matlab.ui.control.EditField
        ThedimensionofthematrixEditFieldLabel  matlab.ui.control.Label
        condTextArea                   matlab.ui.control.TextArea
        condTextAreaLabel              matlab.ui.control.Label
        matrixTextArea                 matlab.ui.control.TextArea
        matrixTextAreaLabel            matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        Label                          matlab.ui.control.Label
        free_gauss_Button              matlab.ui.control.Button
        all_gauss_Button               matlab.ui.control.Button
        col_gauss_Button               matlab.ui.control.Button
        order_gauss_Button             matlab.ui.control.Button
        cal_cond_Button                matlab.ui.control.Button
        set_matrix_Button              matlab.ui.control.Button
        lossAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: set_matrix_Button
        function set_matrix_ButtonPushed(app, event)
            % num_matrix = 5;
            num_matrix = app.ThedimensionofthematrixEditField.Value;
            % add the function path 
            addpath("实验3.1\");
            [A,b] = set_equation(str2double(num_matrix));
            app.matrixTextArea.Value = mat2str([A,b]);
            % app.matrixTextArea.Value = num2str(1.5)
        end

        % Button pushed function: cal_cond_Button
        function cal_cond_ButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            matrix = eval(matrix{1});
            % disp(matrix);
            % add the function path
            addpath('实验3.1\');
            [X, cond_1, cond_2, cond_inf] = cal_cond(matrix(:,1:end-1),matrix(:,end));
            % 输出条件数
            app.condTextArea.Value = ['1-条件数:', num2str(cond_1),newline,...
                '2-条件数:', num2str(cond_2), newline, ...
                '无穷条件数:', num2str(cond_inf)];
            % 输出精确解
            app.AccuratesolutionTextArea.Value = mat2str(X);
        end

        % Button pushed function: order_gauss_Button
        function order_gauss_ButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            matrix = eval(matrix{1});
            A = matrix(:, 1:end-1);
            b = matrix(:, end);
            % add the function path
            addpath('实验3.1\');
            x = order_gauss(A,b);
            app.SolutionTextArea.Value = mat2str(x);
            % calculate the loss
            solution = app.AccuratesolutionTextArea.Value;
            solution = eval(solution{1});
            [loss1, ~, ~] = cal_loss(solution, x);
            % show the loss: 1-norm
            app.LossTextArea.Value = num2str(loss1);
        end

        % Button pushed function: col_gauss_Button
        function col_gauss_ButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            matrix = eval(matrix{1});
            A = matrix(:, 1:end-1);
            b = matrix(:, end);
            % add the function path
            addpath('实验3.1\');
            x = col_gauss(A,b);
            app.SolutionTextArea.Value = mat2str(x);
            % calculate the loss
            solution = app.AccuratesolutionTextArea.Value;
            solution = eval(solution{1});
            [loss1, ~, ~] = cal_loss(solution, x);
            % show the loss: 1-norm
            app.LossTextArea.Value = num2str(loss1);
        end

        % Button pushed function: all_gauss_Button
        function all_gauss_ButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            matrix = eval(matrix{1});
            A = matrix(:, 1:end-1);
            b = matrix(:, end);
            % add the function path
            addpath('实验3.1\');
            x = all_gauss(A, b);
            app.SolutionTextArea.Value = mat2str(x);
            % calculate the loss
            solution = app.AccuratesolutionTextArea.Value;
            solution = eval(solution{1});
            [loss1, ~, ~] = cal_loss(solution, x);
            % show the loss: 1-norm
            app.LossTextArea.Value = num2str(loss1);
        end

        % Button pushed function: free_gauss_Button
        function free_gauss_ButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            matrix = eval(matrix{1});
            A = matrix(:, 1:end-1);
            b = matrix(:, end);
            % add the function path
            addpath('实验3.1\');
            x = free_gauss(A, b);
            app.SolutionTextArea.Value = mat2str(x);
            % calculate the loss
            solution = app.AccuratesolutionTextArea.Value;
            solution = eval(solution{1});
            [loss1, ~, ~] = cal_loss(solution, x);
            % show the loss: 1-norm
            app.LossTextArea.Value = num2str(loss1);
        end

        % Button pushed function: visualresultButton
        function visualresultButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.lossAxes = uiaxes(app.UIFigure);
            title(app.lossAxes, 'Title')
            xlabel(app.lossAxes, 'X')
            ylabel(app.lossAxes, 'Y')
            zlabel(app.lossAxes, 'Z')
            app.set_matrix_Button = uibutton(app.UIFigure, 'push');
            app.set_matrix_Button.ButtonPushedFcn = createCallbackFcn(app, @set_matrix_ButtonPushed, true);
            app.set_matrix_Button.Text = 'set_matrix_Button';
            app.cal_cond_Button = uibutton(app.UIFigure, 'push');
            app.cal_cond_Button.ButtonPushedFcn = createCallbackFcn(app, @cal_cond_ButtonPushed, true);
            app.cal_cond_Button.Text = 'cal_cond_Button';
            app.order_gauss_Button = uibutton(app.UIFigure, 'push');
            app.order_gauss_Button.ButtonPushedFcn = createCallbackFcn(app, @order_gauss_ButtonPushed, true);
            app.order_gauss_Button.Text = 'order_gauss_Button';
            app.col_gauss_Button = uibutton(app.UIFigure, 'push');
            app.col_gauss_Button.ButtonPushedFcn = createCallbackFcn(app, @col_gauss_ButtonPushed, true);
            app.col_gauss_Button.Text = 'col_gauss_Button';
            app.all_gauss_Button = uibutton(app.UIFigure, 'push');
            app.all_gauss_Button.ButtonPushedFcn = createCallbackFcn(app, @all_gauss_ButtonPushed, true);
            app.all_gauss_Button.Text = 'all_gauss_Button';
            app.free_gauss_Button = uibutton(app.UIFigure, 'push');
            app.free_gauss_Button.ButtonPushedFcn = createCallbackFcn(app, @free_gauss_ButtonPushed, true);
            app.free_gauss_Button.Text = 'free_gauss_Button';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '线性方程组求解器';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.matrixTextAreaLabel = uilabel(app.UIFigure);
            app.matrixTextAreaLabel.Text = 'matrix';
            app.matrixTextArea = uitextarea(app.UIFigure);
            app.condTextAreaLabel = uilabel(app.UIFigure);
            app.condTextAreaLabel.Text = 'cond';
            app.condTextArea = uitextarea(app.UIFigure);
            app.ThedimensionofthematrixEditFieldLabel = uilabel(app.UIFigure);
            app.ThedimensionofthematrixEditFieldLabel.Text = 'The dimension of the matrix';
            app.ThedimensionofthematrixEditField = uieditfield(app.UIFigure, 'text');
            app.AccuratesolutionTextAreaLabel = uilabel(app.UIFigure);
            app.AccuratesolutionTextAreaLabel.Text = 'Accurate solution';
            app.AccuratesolutionTextArea = uitextarea(app.UIFigure);
            app.SolutionTextAreaLabel = uilabel(app.UIFigure);
            app.SolutionTextAreaLabel.Text = ' Solution';
            app.SolutionTextArea = uitextarea(app.UIFigure);
            app.visualresultButton = uibutton(app.UIFigure, 'push');
            app.visualresultButton.ButtonPushedFcn = createCallbackFcn(app, @visualresultButtonPushed, true);
            app.visualresultButton.Text = 'visual-result';
            app.LossTextAreaLabel = uilabel(app.UIFigure);
            app.LossTextAreaLabel.Text = 'Loss';
            app.LossTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appfunc_solv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end